classdef Cutting_tool_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1            matlab.ui.Figure
        Load_old           matlab.ui.container.Menu
        help               matlab.ui.container.Menu
        GridLayout         matlab.ui.container.GridLayout
        Load               matlab.ui.control.Button
        markerPanelSerial  matlab.ui.container.Panel
        endSerial          matlab.ui.control.EditField
        beginSerial        matlab.ui.control.EditField
        marker_nrs         matlab.ui.control.EditField
        text30             matlab.ui.control.Label
        text29             matlab.ui.control.Label
        text28             matlab.ui.control.Label
        text27             matlab.ui.control.Label
        manualPanel        matlab.ui.container.Panel
        text26             matlab.ui.control.Label
        selectManual       matlab.ui.control.Button
        uipanel6           matlab.ui.container.Panel
        cut                matlab.ui.control.Button
        Preview            matlab.ui.control.Button
        cuttingMethod      matlab.ui.control.DropDown
        uipanel2           matlab.ui.container.Panel
        file_size_new      matlab.ui.control.Label
        filename           matlab.ui.control.Label
        filesize           matlab.ui.control.Label
        markerPanelTTL     matlab.ui.container.Panel
        on_offset          matlab.ui.control.DropDown
        text31             matlab.ui.control.Label
        text3              matlab.ui.control.Label
        eind               matlab.ui.control.EditField
        text11             matlab.ui.control.Label
        text9              matlab.ui.control.Label
        beg                matlab.ui.control.EditField
        col_nr             matlab.ui.control.EditField
        text19             matlab.ui.control.Label
        timePanel          matlab.ui.container.Panel
        num_cut            matlab.ui.control.Label
        text16             matlab.ui.control.Label
        text15             matlab.ui.control.Label
        text13             matlab.ui.control.Label
        text14             matlab.ui.control.Label
        num_cuts           matlab.ui.control.EditField
        per                matlab.ui.control.EditField
        t_aft              matlab.ui.control.EditField
        t_bev              matlab.ui.control.EditField
        event_t            matlab.ui.control.EditField
        text20             matlab.ui.control.Label
        axes1              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        EEG % Description
    end
    
    methods (Access = private)
        function plotData(app, handles)
            
            EEG = app.EEG;
            
            [nrofsamples, nrofchans] = size(EEG.data);
            a = linspace(0,1,nrofchans);
            b = sort(a,'descend');
            hold(app.axes1, 'on')
            % for ichan=1:nrofchans
            %     if ichan<9
            %         plot(handles.axes1,t,EEG.data(:,ichan)*100+b(ichan)); hold(handles.axes1,'on')
            %     else
            %         plot(handles.axes1,t,EEG.data(:,ichan)./250+b(ichan),'k'); hold(handles.axes1,'on')
            %     end
            % end
            
            %
            for ichan=1:nrofchans
                % plot physiological channels
                if ismember(EEG.channelTypes(ichan), ["EEG" "EMG" "ECG" "EOG"])
                    plot(app.axes1,EEG.time,EEG.data(:,ichan)*100+b(ichan)); hold(app.axes1,'on')
                elseif strcmp(EEG.channelTypes(ichan), "Marker")
                    % plot markers channels
                    if ~any(~ismember(unique(round(EEG.data(:,ichan)))', [0 5])) % see if all values are 0 or 5
                        % plot TTL markers
                        plot(app.axes1,EEG.time,EEG.data(:,ichan)./250+b(ichan),'k'); hold(handles.axes1,'on')
                    else
                        resiseFactor = 20;
                        % plot serial markers as
                        stem(EEG.time,(EEG.data(:,ichan)>0)/resiseFactor)
                        % add marker values as text
                        markerLocs   = find(EEG.data(:,ichan) > 0);
                        markerValues = EEG.data(markerLocs,ichan);
                        text(markerLocs/EEG.fsample, repmat(1/resiseFactor, [1,length(markerValues)]), num2str(markerValues))
                    end
                end
            end
            %
            if length(handles.axes1.Children) == length(EEG.channelLabels)
                legend([EEG.channelLabels])
            elseif length(handles.axes1.Children) == length(EEG.channelLabels)+1
                legend(['cuts' EEG.channelLabels])
            end
            grid(app.axes1,'on')
            app.axes1.XLabel.String = 'times (s)';
            axis(app.axes1, 'tight')
        end
        
        function row_nr_CreateFcn(app, hObject, eventdata, handles)
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function resetInteractions(app, event)
            % This function resets the states of the toggle tools that
            % impact user interactions.  It also resets the figure interactions.
             
            % Find all tools to reset.  Exclude the tool associated
            % with the event.
            interactiveTools = [app.uitoggletool1, app.uitoggletool2, app.uitoggletool3, app.uitoggletool4];
            interactiveTools(event.Source == interactiveTools) = [];
             
            % Set the state of the tools to 'off'.
            [interactiveTools.State] = deal('off');
             
            % Set figure interactions to 'off'.
            datacursormode(app.figure1, 'off')
            rotate3d(app.figure1, 'off');
            pan(app.figure1, 'off');
            zoom(app.figure1,'off');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Cutting_tool_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            set(get(handles.markerPanelSerial, 'Children'), 'Enable', 'off')
            set(get(handles.timePanel, 'Children'), 'Enable', 'off')
            set(get(handles.manualPanel, 'Children'), 'Enable', 'off')
            
            handles.output = hObject;
            % handles.dir = varargin{1}.dir;
            guidata(hObject, handles);
        end

        % Menu selected function: Load_old
        function Load_old_Callback(app, event)
%             [filename, EEG] = EEGLoadData('time');

            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
%             [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            [filename, EEG] = EEGLoadData('time');
            if any(filename) % check is any file was selected
                app.filename.Text= ['filename: ' filename]; % display filename
%                 app.trial.Text = '1';
%                 app.chan.Text = '1';
                % if it is a matrix (regular EEG data) save data to handles
                app.EEG = EEG;
                [d1, d2, d3] = size(EEG.data);  % determine the data dimensions
                cla(app.axes1);
                app.filesize.Text = sprintf('original file size: %i - %i - %i',d1,d2,d3); % display filesize
                app.file_size_new.Text = '';
                app.chan.Text = 1;
                app.trial.Text = 1;
                plotData(app, handles);
                figure(app.figure1);
            end
%             guidata(hObject,handles)
        end

        % Button pushed function: Preview
        function Preview_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            if ~isfield(handles, 'EEG')
                warndlg('No data available.')
                return
            end
            EEG = handles.EEG;
            
            ax1 = handles.axes1;
            Ylimits = ax1.YLim;
            
            sampleWarning = {'No samples available';'You are trying to cut samples outside of your dataset.';'Adjust your settings.'};
            % cuttingMethods:
            % 1 = marker based cuts TTL
            % 2 = marker based cuts Serial
            % 3 = time based cuts
            % 4 = manual selection (to be implemented)
            %% cut based on TTL markers
            if handles.cuttingMethod.Value == 1
                % check cutting parameters
                selectedChan = str2double(handles.col_nr.String);
                preMarker = str2double(handles.beg.String);
                postMarker = str2double(handles.eind.String);
                if any(isnan([selectedChan preMarker postMarker]))
                    opts.Interpreter = 'tex';
                    opts.WindowStyle = 'modal';
                    warndlg('\fontsize{16}Please select the right method and fill in the cutting parameters.', 'No Settings found', opts)
                    return
                end
                if handles.on_offset.Value == 1
                    markers = find(round(diff(EEG.data(:,selectedChan)))==5 );
                elseif handles.on_offset.Value == 2
                    markers = find(round(diff(EEG.data(:,selectedChan)))==-5);
                end
                nrofevents = length(markers);
                if nrofevents == 0
                    warndlg('No markers detected within this channel. Make sure to select a Marker channel containing event markers', 'No events detected.','non-modal')
                    return
                end
                hold(handles.axes1, 'on')
                for imark = 1:nrofevents
                    if markers(imark)-preMarker < 0
                        errordlg(sampleWarning)
                    elseif markers(imark) + postMarker > size(EEG.data,1)
                        errordlg(sampleWarning)
                    end
                end
                segmentStart = (markers-preMarker) / EEG.fsample;
                segmentEnd = (markers + postMarker) / EEG.fsample;
            %% Cut based on serial markers
                elseif handles.cuttingMethod.Value == 2
                markerChannel = find(EEG.channelTypes=="Marker");
                preMarker = str2double(handles.beginSerial.String);
                postMarker = str2double(handles.endSerial.String);
                if any(isnan([markerChannel preMarker postMarker]))
                    opts.Interpreter = 'tex';
                    opts.WindowStyle = 'modal';
                    warndlg('\fontsize{16} Please fill in the cutting parameters.', 'No Settings found', opts)
                    return
                end
                markers = find(ismember(EEG.data(:,markerChannel), str2double(strsplit(handles.marker_nrs.String))));
                nrofevents = length(markers);
                if nrofevents == 0
                    warndlg('No markers detected within this channel. Make sure to select a Marker channel containing event markers and provide correct marker numbers', 'No events detected.','non-modal')
                    return
                end
                for imark = 1:nrofevents
                    if markers(imark)-preMarker < 0
                        errordlg(sampleWarning)
                    elseif markers(imark) + postMarker > size(EEG.data,1)
                        errordlg(sampleWarning)
                    end
                end
                segmentStart = (markers-preMarker) / EEG.fsample;
                segmentEnd = (markers + postMarker) / EEG.fsample;
            
            %% Cut based on time periods
            elseif handles.cuttingMethod.Value == 3
                firstEvent = str2double(handles.event_t.String);
                periodEvent = str2double(handles.per.String);
                nrofevents = str2double(handles.num_cuts.String);
                preOnset = str2double(handles.t_bev.String);
                postOnset = str2double(handles.t_aft.String);
            
                if any(isnan([firstEvent periodEvent nrofevents preOnset postOnset]))
                    opts.Interpreter = 'tex';
                    opts.WindowStyle = 'modal';
                    warndlg('\fontsize{16} Please fill in the cutting parameters.', 'No Settings found', opts)
                    return
                end
            
                segmentStart = [ [firstEvent firstEvent + (periodEvent * (1:(nrofevents-1)))] - preOnset ]';
                segmentEnd   = [ [firstEvent firstEvent + (periodEvent * (1:(nrofevents-1)))] + postOnset ]';
            
                if any(segmentStart < 0)
                    errordlg(sampleWarning)
                elseif any(segmentEnd > size(EEG.data,1))
                    errordlg(sampleWarning)
                end
            
            %% Cut based on manual selection
            elseif handles.cuttingMethod.Value == 4
                if ~isfield(handles, 'windowEdges')
                    opts.Interpreter = 'tex';
                    opts.WindowStyle = 'modal';
                    warndlg('\fontsize{16} Please select a time window first.', 'No Settings found', opts)
                    return
                end
                nrofevents = 1;
                segmentStart = handles.windowEdges(1);
                segmentEnd = handles.windowEdges(2);
            end
            cla(ax1)
            if ~isempty(segmentStart)
                handles.windowEdges = reshape([segmentStart segmentEnd]', 1, []);
                handles.windowEdges = [segmentStart segmentEnd];
            
                p = patch([segmentStart segmentStart segmentEnd segmentEnd]', ...
                    repmat([Ylimits(1) Ylimits(2) Ylimits(2) Ylimits(1)]',[1 nrofevents ]), 'b');
                p.FaceAlpha = .2;
            end
            plotData(app, handles)
            guidata(hObject,handles)
        end

        % Button pushed function: cut
        function cut_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            if ~isfield(handles, 'EEG')
                warndlg('No data available.')
                return
            end
            handles = Preview_Callback(hObject, eventdata, handles);
            EEG = handles.EEG;
            if ~isfield(handles, 'windowEdges')
                opts.Interpreter = 'tex';
                opts.WindowStyle = 'modal';
                warndlg('\fontsize{16} Please fill in the cutting parameters.', 'No Settings found', opts)
                return
            end
            windowEdges = ceil(handles.windowEdges * 256);
            nrofcuts = size(windowEdges,1);
            if nrofcuts == 0
                warndlg('No events detected within this channel. Make sure to select a Marker channel containing event Markers', 'No events detected.')
                return
            end
            
            cuts = zeros(windowEdges(1,2)-windowEdges(1,1), size(EEG.data,2), nrofcuts);
            % cuttingMethods:
            % 1 = marker based cuts
            % 2 = time based cuts
            % 3 = manual selection
            % if handles.cuttingMethod.Value == 1 || handles.cuttingMethod.Value == 2
            for icut = 1:nrofcuts
                cut = EEG.data(windowEdges(icut,1):(windowEdges(icut,2)-1),:);
                cuts(:,:,icut) = cut;
            end
            
            % elseif handles.cuttingMethod.Value == 3
            %     opts.WindowStyle = 'modal';
            %     opts.Interpreter = 'tex';
            %     warndlg('\fontsize{15} This method is not implemented yet :(' , 'Method unavailable' , opts)
            % end
            handles.file_size_new.String = sprintf('new file size: %d - %d - %d', size(EEG.data,[1 2 3]));
            
            % save the cut data
            EEG.data = cuts;
            EEG.dims = [EEG.dims "trials"];
            %% TO DO
            % add time per trial
            % add sampleinfo per trial
            % add history --> do this in the cut_Callback
            EEGSaveData(EEG,'cut');
            handles = rmfield(handles, 'windowEdges');
            guidata(hObject,handles)
        end

        % Value changed function: cuttingMethod
        function cuttingMethod_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            if handles.cuttingMethod.Value == 1
                set(get(handles.markerPanelTTL, 'Children'), 'Enable', 'on')
%                 handles.markerPanelTTL.ShadowColor = 'k';
%                 handles.markerPanelTTL.BorderWidth = 2;
            
                set(get(handles.markerPanelSerial, 'Children'), 'Enable', 'off')
%                 handles.markerPanelSerial.ShadowColor = [.7 .7 .7];
%                 handles.markerPanelSerial.BorderWidth = 1;
            
                set(get(handles.timePanel, 'Children'), 'Enable', 'off')
%                 handles.timePanel.ShadowColor = [.7 .7 .7];
%                 handles.timePanel.BorderWidth = 1;
            
                set(get(handles.manualPanel, 'Children'), 'Enable', 'off')
%                 handles.manualPanel.ShadowColor = [.7 .7 .7];
%                 handles.manualPanel.BorderWidth = 1;
            
            elseif handles.cuttingMethod.Value == 2
            
                set(get(handles.markerPanelTTL, 'Children'), 'Enable', 'off')
%                 handles.markerPanelTTL.ShadowColor = [.7 .7 .7];
%                 handles.markerPanelTTL.BorderWidth = 1;
            
                set(get(handles.markerPanelSerial, 'Children'), 'Enable', 'on')
%                 handles.markerPanelSerial.ShadowColor = 'k';
%                 handles.markerPanelSerial.BorderWidth = 2;
            
                set(get(handles.timePanel, 'Children'), 'Enable', 'off')
%                 handles.timePanel.ShadowColor = [.7 .7 .7];
%                 handles.timePanel.BorderWidth = 1;
            
                set(get(handles.manualPanel, 'Children'), 'Enable', 'off')
%                 handles.manualPanelTTL.ShadowColor = [.7 .7 .7];
%                 handles.manualPanel.BorderWidth = 1;
            
            elseif handles.cuttingMethod.Value == 3
                set(get(handles.markerPanelTTL, 'Children'), 'Enable', 'off')
%                 handles.markerPanelTTL.ShadowColor = [.7 .7 .7];
%                 handles.markerPanelTTL.BorderWidth = 1; 
            
                set(get(handles.markerPanelSerial, 'Children'), 'Enable', 'off')
%                 handles.markerPanelSerial.ShadowColor = [.7 .7 .7];
%                 handles.markerPanelSerial.BorderWidth = 1;
            
                set(get(handles.timePanel, 'Children'), 'Enable', 'on')
%                 handles.timePanel.ShadowColor = 'k';
%                 handles.timePanel.BorderWidth = 2;
            
                set(get(handles.manualPanel, 'Children'), 'Enable', 'off')
%                 handles.manualPanel.ShadowColor = [.7 .7 .7];
%                 handles.manualPanel.BorderWidth = 1;
            
            elseif handles.cuttingMethod.Value == 4
%                 set(get(handles.markerPanelTTL, 'Children'), 'Enable', 'off')
%                 handles.markerPanelTTL.ShadowColor = [.7 .7 .7];
                handles.markerPanelTTL.BorderWidth = 1;
            
                set(get(handles.markerPanelSerial, 'Children'), 'Enable', 'off')
%                 handles.markerPanelSerial.ShadowColor = [.7 .7 .7];
%                 handles.markerPanelSerial.BorderWidth = 1;
            
                set(get(handles.timePanel, 'Children'), 'Enable', 'off')
%                 handles.timePanel.ShadowColor = [.7 .7 .7];
%                 handles.timePanel.BorderWidth = 1;
            
                set(get(handles.manualPanel, 'Children'), 'Enable', 'on')
%                 handles.manualPanel.ShadowColor = 'k';
%                 handles.manualPanel.BorderWidth = 2;
            end
        end

        % Menu selected function: help
        function help_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            web('Event_cutter_help.htm', '-helpbrowser')
        end

        % Button pushed function: selectManual
        function selectManual_Callback(app, event)
            % --- Executes on button press in selectManual.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.axes1;
            [selection,~] = ginput(2);
            handles.windowEdges = selection';
            guidata(hObject,handles)
            Preview_Callback(hObject, eventdata, handles)
        end

        % Callback function: not associated with a component
        function uitoggletool4_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable data tips based on the
            % tool's current state.
            state = app.uitoggletool4.State;
            datacursormode(app.figure1, char(state));
        end

        % Callback function: not associated with a component
        function uitoggletool3_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable pan based on the
            % tool's current state.
            state = app.uitoggletool3.State;
            pan(app.figure1, char(state));
        end

        % Callback function: not associated with a component
        function uitoggletool2_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable zoom-out based on the
            % tool's current state.
            state = app.uitoggletool2.State;
            zoomModeObject = zoom(app.figure1);
            if state
                zoomModeObject.Direction = 'out';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Callback function: not associated with a component
        function uitoggletool1_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable zoom-in based on the
            % tool's current state.
            state = app.uitoggletool1.State;
            zoomModeObject = zoom(app.figure1);
            if state
                zoomModeObject.Direction = 'in';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Button pushed function: Load
        function LoadButtonPushed(app, event)


            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

              [filename, EEG] = EEGLoadData('time');
            if any(filename) % check is any file was selected
                app.filename.Text= ['filename: ' filename]; % display filename
%                 app.trial.Text = '1';
%                 app.chan.Text = '1';
                % if it is a matrix (regular EEG data) save data to handles
                app.EEG = EEG;
                [d1, d2, d3] = size(EEG.data);  % determine the data dimensions
                cla(app.axes1);
                app.filesize.Text = sprintf('original file size: %i - %i - %i',d1,d2,d3); % display filesize
                app.file_size_new.Text = '';
%                 app.chan.Text = 1;
%                 app.trial.Text = 1;
                plotData(app, handles);
                figure(app.figure1);
            end
%             guidata(hObject,handles)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Cutting_tool';
            app.figure1.Tag = 'figure1';
            app.Load_old = uimenu(app.figure1);
            app.Load_old.MenuSelectedFcn = createCallbackFcn(app, @Load_old_Callback, true);
            app.Load_old.Text = 'Load';
            app.Load_old.Tag = 'Load';
            app.help = uimenu(app.figure1);
            app.help.MenuSelectedFcn = createCallbackFcn(app, @help_Callback, true);
            app.help.Text = 'Help';
            app.help.Tag = 'help';
            app.GridLayout = uigridlayout(app.figure1);
            app.axes1 = uiaxes(app.GridLayout);
            app.axes1.Tag = 'axes1';
            app.timePanel = uipanel(app.GridLayout);
            app.timePanel.Tag = 'timePanel';
            app.text20 = uilabel(app.timePanel);
            app.text20.Tag = 'text20';
            app.text20.Text = 'Time based cuts';
            app.event_t = uieditfield(app.timePanel, 'text');
            app.event_t.Tag = 'event_t';
            app.event_t.Tooltip = 'Fill in the time (in sec) on which the first event takes place';
            app.t_bev = uieditfield(app.timePanel, 'text');
            app.t_bev.Tag = 't_bev';
            app.t_bev.Tooltip = 'Fill in how many seconds you want to cut before each event. For values smaller than 1, use a dot.';
            app.t_aft = uieditfield(app.timePanel, 'text');
            app.t_aft.Tag = 't_aft';
            app.t_aft.Tooltip = 'Fill in how many seconds you want to cut after each event. For values smaller than 1, use a dot.';
            app.per = uieditfield(app.timePanel, 'text');
            app.per.Tag = 'per';
            app.per.Tooltip = 'Fill in the total duration (in sec) of the period between two events.';
            app.num_cuts = uieditfield(app.timePanel, 'text');
            app.num_cuts.Tag = 'num_cuts';
            app.num_cuts.Tooltip = 'Fill in the number of cuts you want to make, based on the number of trials you want to create.';
            app.text14 = uilabel(app.timePanel);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Time first event';
            app.text13 = uilabel(app.timePanel);
            app.text13.Tag = 'text13';
            app.text13.Tooltip = 'Fill in how many seconds you want to cut before each event (for values smaller than 1, use a dot).';
            app.text13.Text = 'Seconds to cut before event';
            app.text15 = uilabel(app.timePanel);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Seconds after event';
            app.text16 = uilabel(app.timePanel);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Period events';
            app.num_cut = uilabel(app.timePanel);
            app.num_cut.Tag = 'num_cut';
            app.num_cut.Text = 'Number of cuts';
            app.markerPanelTTL = uipanel(app.GridLayout);
            app.markerPanelTTL.Tag = 'markerPanelTTL';
            app.text19 = uilabel(app.markerPanelTTL);
            app.text19.Tag = 'text19';
            app.text19.Text = 'Marker based cuts (TTL)';
            app.col_nr = uieditfield(app.markerPanelTTL, 'text');
            app.col_nr.Tag = 'col_nr';
            app.col_nr.Tooltip = 'Fill in the channel number which contains the TTL''s of your interest (usually channel nr. 9 - 12).';
            app.beg = uieditfield(app.markerPanelTTL, 'text');
            app.beg.Tag = 'beg';
            app.beg.Tooltip = 'Fill in how many samples you want to keep before the TTL onset for each event. ';
            app.text9 = uilabel(app.markerPanelTTL);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Samples before marker on/offset: ';
            app.text11 = uilabel(app.markerPanelTTL);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Samples after marker on/offset:';
            app.eind = uieditfield(app.markerPanelTTL, 'text');
            app.eind.Tag = 'eind';
            app.eind.Tooltip = 'Fill in how many samples you want to keep after the TTL onset for each event. ';
            app.text3 = uilabel(app.markerPanelTTL);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Channel number:';
            app.text31 = uilabel(app.markerPanelTTL);
            app.text31.Tag = 'text31';
            app.text31.Text = 'cut on ';
            app.on_offset = uidropdown(app.markerPanelTTL);
            app.on_offset.Items = {'onset', 'offset'};
            app.on_offset.Tag = 'on_offset';
            app.on_offset.Value = 'onset';
            app.uipanel2 = uipanel(app.GridLayout);
            app.uipanel2.Tag = 'uipanel2';
            app.filesize = uilabel(app.uipanel2);
            app.filesize.Tag = 'filesize';
            app.filesize.Text = 'original file size:';
            app.filename = uilabel(app.uipanel2);
            app.filename.Tag = 'filename';
            app.filename.Text = 'filename';
            app.file_size_new = uilabel(app.uipanel2);
            app.file_size_new.Tag = 'file_size_new';
            app.file_size_new.Text = '';
            app.uipanel6 = uipanel(app.GridLayout);
            app.uipanel6.Tag = 'uipanel6';
            app.cuttingMethod = uidropdown(app.uipanel6);
            app.cuttingMethod.Items = {'Marker based cuts (TTL)', 'Marker based cuts (Serial)', 'Time based cuts', 'Cut manual selection'};
            app.cuttingMethod.ValueChangedFcn = createCallbackFcn(app, @cuttingMethod_Callback, true);
            app.cuttingMethod.Tag = 'cuttingMethod';
            app.cuttingMethod.Value = 'Marker based cuts (TTL)';
            app.Preview = uibutton(app.uipanel6, 'push');
            app.Preview.ButtonPushedFcn = createCallbackFcn(app, @Preview_Callback, true);
            app.Preview.Tag = 'Preview';
            app.Preview.Text = 'Preview';
            app.cut = uibutton(app.uipanel6, 'push');
            app.cut.ButtonPushedFcn = createCallbackFcn(app, @cut_Callback, true);
            app.cut.Tag = 'cut';
            app.cut.Text = 'Cut';
            app.manualPanel = uipanel(app.GridLayout);
            app.manualPanel.Tag = 'manualPanel';
            app.selectManual = uibutton(app.manualPanel, 'push');
            app.selectManual.ButtonPushedFcn = createCallbackFcn(app, @selectManual_Callback, true);
            app.selectManual.Tag = 'selectManual';
            app.selectManual.Text = 'Select time window';
            app.text26 = uilabel(app.manualPanel);
            app.text26.Tag = 'text26';
            app.text26.Text = 'Manual selection';
            app.markerPanelSerial = uipanel(app.GridLayout);
            app.markerPanelSerial.Tag = 'markerPanelSerial';
            app.text27 = uilabel(app.markerPanelSerial);
            app.text27.Tag = 'text27';
            app.text27.Text = 'Marker based cuts (Serial)';
            app.text28 = uilabel(app.markerPanelSerial);
            app.text28.Tag = 'text28';
            app.text28.Text = 'Marker number(s) to cut';
            app.text29 = uilabel(app.markerPanelSerial);
            app.text29.Tag = 'text29';
            app.text29.Text = 'Samples before marker';
            app.text30 = uilabel(app.markerPanelSerial);
            app.text30.Tag = 'text30';
            app.text30.Text = 'Samples after marker:';
            app.marker_nrs = uieditfield(app.markerPanelSerial, 'text');
            app.marker_nrs.Tag = 'marker_nrs';
            app.marker_nrs.Tooltip = 'Fill in the marker numbers as they are coded in the experiment';
            app.beginSerial = uieditfield(app.markerPanelSerial, 'text');
            app.beginSerial.InputType = 'digits';
            app.beginSerial.Tag = 'beginSerial';
            app.beginSerial.Tooltip = 'Fill in how many samples you want to keep before the TTL onset for each event. ';
            app.endSerial = uieditfield(app.markerPanelSerial, 'text');
            app.endSerial.Tag = 'endSerial';
            app.endSerial.Tooltip = 'Fill in how many samples you want to keep before the TTL onset for each event. ';
            app.Load = uibutton(app.GridLayout, 'push');
            app.Load.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.Load.Text = 'Load';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cutting_tool_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)Cutting_tool_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end