classdef Filter_tool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        Panel                          matlab.ui.container.Panel
        nextButton                     matlab.ui.control.Button
        previousButton                 matlab.ui.control.Button
        ChannelIndex                   matlab.ui.control.Label
        FiltersettingsButtonGroup      matlab.ui.container.ButtonGroup
        ApplyfilterButton              matlab.ui.control.Button
        AttenuationdBfilterstrengthEditField  matlab.ui.control.NumericEditField
        AttenuationdBfilterstrengthLabel  matlab.ui.control.Label
        CutoffFiltOpts                 matlab.ui.container.ButtonGroup
        CutofffrequencyEditField       matlab.ui.control.NumericEditField
        HighPassButton                 matlab.ui.control.RadioButton
        LowpassButton                  matlab.ui.control.RadioButton
        CutofffrequencyEditFieldLabel  matlab.ui.control.Label
        CutofffilterButton             matlab.ui.control.RadioButton
        BandFilterOpts                 matlab.ui.container.ButtonGroup
        UpperlimitEditField            matlab.ui.control.NumericEditField
        LowerlimitEditField            matlab.ui.control.NumericEditField
        UpperlimitEditFieldLabel       matlab.ui.control.Label
        LowerlimitEditFieldLabel       matlab.ui.control.Label
        BandpassButton_2               matlab.ui.control.RadioButton
        BandstopButton                 matlab.ui.control.RadioButton
        BandfilterButton               matlab.ui.control.RadioButton
        ChannelselectionPanel          matlab.ui.container.Panel
        GSRCheckBox                    matlab.ui.control.CheckBox
        EMGCheckBox                    matlab.ui.control.CheckBox
        ECGCheckBox                    matlab.ui.control.CheckBox
        EOGCheckBox                    matlab.ui.control.CheckBox
        EEGCheckBox                    matlab.ui.control.CheckBox
        SelectchanneltypestofilterLabel  matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        LoadButton                     matlab.ui.control.Button
        filesizeText                   matlab.ui.control.Label
        filenameText                   matlab.ui.control.Label
        filteredPower                  matlab.ui.control.UIAxes
        filteredSignal                 matlab.ui.control.UIAxes
        unfilteredPower                matlab.ui.control.UIAxes
        unfilteredSignal               matlab.ui.control.UIAxes
    end


    properties (Access = public)
        EEG % EEG data struct
    end
    
    properties (Access = private)
        filtered % filtered data
        channelnr = 1 %  channelnr to plot
        channelTypes = ["EEG" "EOG" "ECG" "EMG" "GSR"];

        channels2filter % list of channels to filter, depending on their types
    end
    
    methods (Access = private)
        
        function plotUnfiltered(app)
            %% Plot data from loaded file
            % plot time series data
            plot(app.unfilteredSignal, app.EEG.time, app.EEG.data(:,app.channels2filter(app.channelnr)))
            xlim(app.unfilteredSignal,'tight')

            % Calculate and plot power spectrum
            [pxx,w] = pwelch(app.EEG.data(:,app.channels2filter(app.channelnr)),[],[],[], app.EEG.fsample);
            plot(app.unfilteredPower, w, log10(pxx))     
%             legend(app.unfilteredPower, app.EEG.channelLabels(app.channelnr))   
            nrofchannels = size(app.EEG.data,2);
            app.ChannelIndex.Text = sprintf('Channels: %s  (%i / %i)', app.EEG.channelLabels(app.channelnr), app.channelnr, nrofchannels);
           
        end

        function plotFiltered(app)
            % plot data after filtering

            % update channel index
            nrofchannels = size(app.EEG.data,2);
            app.ChannelIndex.Text = sprintf('Channels: %s  (%i / %i)', app.EEG.channelLabels(app.channelnr), app.channelnr, nrofchannels);

            % plot time series data
            plot(app.filteredSignal, app.EEG.time, app.filtered.data(:,app.channels2filter(app.channelnr)))
            
            % Calculate and plot power spectrum
            [pxx,w] = pwelch(app.filtered.data(:,app.channels2filter(app.channelnr)),[],[],[], app.filtered.fsample);
            plot(app.filteredPower, w, log10(pxx))

            % link axes of filterd and unfiltered data so that zooming
            % applies to both
            linkaxes([app.unfilteredSignal app.filteredSignal],'x');
            linkaxes([app.unfilteredPower  app.filteredPower], 'x');
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Check if the signal processing toolbox is installed (required for the
            % cutting tool and  time frequency analysis) and give a warning when this
            % is not the case.
            if exist('bandpass','file') ~= 2 %~license('checkout','Signal_Toolbox')
                opts.WindowStyle = 'modal';
                opts.Interpreter = 'tex';
                toolboxWarning = sprintf([...
                    'The signal processing toolbox is not installed.\n' ...
                    'This is required for some analysis tools so make sure to install it.\n' ...
                    'This can be done in Matlab under the ''HOME'' tab:\n' ...
                    '- Click on ''Add-Ons''\n- Click on ''Get Add-Ons'' .'...
                    ]);
                warndlg(['\fontsize{20}' toolboxWarning],'Matlab version',opts);
                fprintf(['\nWARNING:\n' toolboxWarning '\n']);
            else
                fprintf('Signal Processing Toolbox detected\n')
            end
        end

        % Button pushed function: ApplyfilterButton
        function ApplyfilterButtonPushed(app, event)
            app.filtered = app.EEG;

            msbh = msgbox('Filtering data ...');
            
            % identify channel types to be filtered
            app.channels2filter = find(ismember(app.filtered.channelTypes, app.channelTypes));

            nrofSamples = length(app.EEG.time);
            % mirror and concatenate the data to function as a buffer for
            % edge artefacts
            app.filtered.data = [flipud(app.filtered.data); app.filtered.data ; flipud(app.filtered.data)];
    
            % demean the data to minimize edge artefacts
            app.filtered.data(:,app.channels2filter) = app.filtered.data(:,app.channels2filter) -mean(app.filtered.data(:,app.channels2filter));

            % get attenuation value (the amplitude of the filter)
            attenuation = app.AttenuationdBfilterstrengthEditField.Value;

            % apply the selected filter
            if app.BandfilterButton.Value
                % in case of a band filter, get the lower and upper band
                % limits
                filterFreq = [app.LowerlimitEditField.Value app.UpperlimitEditField.Value];
%                 loFreq = app.LowerlimitEditField.Value;
%                 hiFreq = app.UpperlimitEditField.Value;
                if app.BandstopButton.Value
                    % apply a band STOP filter
                    filterType = "bandstop";
                    [app.filtered.data(:,app.channels2filter)] = bandstop(app.filtered.data(:,app.channels2filter), filterFreq, app.filtered.fsample, "StopbandAttenuation",attenuation,"ImpulseResponse","iir");
                elseif app.BandpassButton_2.Value
                    % apply a band PASS filter
                    filterType = "bandpass";
                    [app.filtered.data(:,app.channels2filter)]= bandpass(app.filtered.data(:,app.channels2filter), filterFreq, app.filtered.fsample, "StopbandAttenuation",attenuation,"ImpulseResponse","iir");
                end
            elseif app.CutofffilterButton.Value
                % in case of a cut-off filter, get the cut-off frequency
                filterFreq = app.CutofffrequencyEditField.Value;
                if app.LowpassButton.Value
                    % apply a LOW PASS filter
                    filterType = "low pass";
                    app.filtered.data(:,app.channels2filter) = lowpass(app.filtered.data(:,app.channels2filter), filterFreq, app.filtered.fsample, "StopbandAttenuation", attenuation,"ImpulseResponse","iir");
                elseif app.HighPassButton.Value
                    % apply a HIGH PASS filter
                    filterType = "high pass";
                    app.filtered.data(:,app.channels2filter) = highpass(app.filtered.data(:,app.channels2filter), filterFreq, app.filtered.fsample, "StopbandAttenuation", attenuation,"ImpulseResponse","iir");
                end
            end

            app.filtered.data = app.filtered.data(nrofSamples+1:2*nrofSamples,:,:);


            app.filtered.history  = app.filtered.history + sprintf("\n\nFilter applied at %s\n" + ...
                "Filtered channels %s\n" + ...
                "Filter settings:\n" + ...
                "Type: %s\n" + ...
                "Frequency: %s\n" + ...
                "Attentuation: %d" ...
                , datetime, strcat(num2str(app.channels2filter)), filterType,strcat(num2str(filterFreq)), attenuation);

            % plot both original and filtered data
            plotFiltered(app)
            plotUnfiltered(app)
            
            % enable buttons to browse through the channels
            app.SaveButton.Enable = "on";
            % delete the message that the tool is busy filtering
            delete(msbh)


        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [filename, app.EEG] = EEGLoadData("time");
            if ~isempty(filename)
                % if file is selected
                % - update filename in GUI
                % - update file size in GUI
                app.filenameText.Text = sprintf('filename:\n%s', app.EEG.filename);
                app.filesizeText.Text = sprintf('filesize:\n%i - %i', (size(app.EEG.data)));

                % check which channels to filter
                app.channels2filter = find(ismember(app.EEG.channelTypes, app.channelTypes));
                
                app.channelnr = 1;

                % when loading new data, clear any existing filtered data
                app.filtered = [];

                % clear plots of filtered data
                cla(app.filteredSignal)
                cla(app.filteredPower)
                
                % plot unfiltered data
                plotUnfiltered(app)

                % enable buttons to browse through the channels                
                app.previousButton.Enable = "on";
                app.nextButton.Enable = "on";
            end
            figure(app.UIFigure)
        end

        % Button pushed function: previousButton
        function previousButtonPushed(app, event)

            % decrease the channel number to plot, in case it already is
            % the first channel, wrap around to the last channel
            if app.channelnr == app.channels2filter(1)
                app.channelnr = app.channels2filter(end);
            else
                app.channelnr = app.channels2filter(find(app.channels2filter==app.channelnr)-1);
            end

            % plot the unfiltered signal, and if present the filtered signal
            plotUnfiltered(app)
            if ~isempty(app.filtered)
                plotFiltered(app)
            end

        end

        % Button pushed function: nextButton
        function nextButtonPushed(app, event)
            % increase the channel number to plot, in case it already is
            % the last channel, wrap around to the first channel
            if app.channelnr == app.channels2filter(end)
                app.channelnr = app.channels2filter(1);
            else
                app.channelnr = app.channels2filter(find(app.channels2filter==app.channelnr)+1);
            end

            % plot the unfiltered signal, and possibly the filtered signal
            plotUnfiltered(app)
            if ~isempty(app.filtered)
                plotFiltered(app)
            end
        end

        % Selection changed function: FiltersettingsButtonGroup
        function FiltersettingsButtonGroupSelectionChanged(app, event)
            selectedButton = app.FiltersettingsButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,  'Band filter')
                app.LowpassButton.Enable = "off";
                app.HighPassButton.Enable = "off";
                app.CutofffrequencyEditField.Enable = "off";
                app.BandstopButton.Enable = "on";
                app.BandpassButton_2.Enable = "on";
                app.LowerlimitEditField.Enable = "on";
                app.UpperlimitEditField.Enable = "on";
            elseif strcmp(selectedButton.Text, 'Cut-off filter')
                app.LowpassButton.Enable = "on";
                app.HighPassButton.Enable = "on";
                app.CutofffrequencyEditField.Enable = "on";
                app.BandstopButton.Enable = "off";
                app.BandpassButton_2.Enable = "off";
                app.LowerlimitEditField.Enable = "off";
                app.UpperlimitEditField.Enable = "off";

            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

            EEGSaveData(app.filtered, 'filt')
        end

        % Value changed function: EEGCheckBox
        function EEGCheckBoxValueChanged(app, event)

            if app.EEGCheckBox.Value 
                app.channelTypes = [app.channelTypes "EEG"];
            else
                app.channelTypes = app.channelTypes(~ismember(app.channelTypes , "EEG"));
            end        
            app.channels2filter = find(ismember(app.EEG.channelTypes, app.channelTypes));
        end

        % Value changed function: EOGCheckBox
        function EOGCheckBoxValueChanged(app, event)

            if app.EOGCheckBox.Value 
                app.channelTypes = [app.channelTypes "EOG"];
            else
                app.channelTypes = app.channelTypes(~ismember(app.channelTypes , "EOG"));
            end        
            app.channels2filter = find(ismember(app.EEG.channelTypes, app.channelTypes));
        end

        % Value changed function: ECGCheckBox
        function ECGCheckBoxValueChanged(app, event)
            if app.ECGCheckBox.Value
                app.channelTypes = [app.channelTypes "ECG"];
            else
                app.channelTypes = app.channelTypes(~ismember(app.channelTypes , "ECG"));
            end        
            app.channels2filter = find(ismember(app.EEG.channelTypes, app.channelTypes));
        end

        % Value changed function: EMGCheckBox
        function EMGCheckBoxValueChanged(app, event)
            if app.EMGCheckBox.Value
                app.channelTypes = [app.channelTypes "EMG"];
            else
                app.channelTypes = app.channelTypes(~ismember(app.channelTypes , "EMG"));
            end        
            app.channels2filter = find(ismember(app.EEG.channelTypes, app.channelTypes));
        end

        % Value changed function: GSRCheckBox
        function GSRCheckBoxValueChanged(app, event)

            if app.GSRCheckBox.Value 
                app.channelTypes = [app.channelTypes "GSR"];
            else
                app.channelTypes = app.channelTypes(~ismember(app.channelTypes , "GSR"));
            end        
            app.channels2filter = find(ismember(app.EEG.channelTypes, app.channelTypes));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.unfilteredSignal = uiaxes(app.GridLayout);
            title(app.unfilteredSignal, 'unfiltered signal')
            xlabel(app.unfilteredSignal, 'Time (sec)')
            ylabel(app.unfilteredSignal, 'Y')
            zlabel(app.unfilteredSignal, 'Z')
            app.unfilteredSignal.Tag = 'unfilteredSignal';
            app.unfilteredPower = uiaxes(app.GridLayout);
            title(app.unfilteredPower, 'unfiltered powerspectrum')
            xlabel(app.unfilteredPower, 'Frequency (Hz)')
            ylabel(app.unfilteredPower, 'Y')
            zlabel(app.unfilteredPower, 'Z')
            app.unfilteredPower.Tag = 'unfilteredPower';
            app.filteredSignal = uiaxes(app.GridLayout);
            title(app.filteredSignal, 'filtered signal')
            xlabel(app.filteredSignal, 'Time (sec)')
            ylabel(app.filteredSignal, 'Y')
            zlabel(app.filteredSignal, 'Z')
            app.filteredSignal.Tag = 'filteredSignal';
            app.filteredPower = uiaxes(app.GridLayout);
            title(app.filteredPower, 'filtered powerspectrum')
            xlabel(app.filteredPower, 'Frequency (Hz)')
            ylabel(app.filteredPower, 'Y')
            zlabel(app.filteredPower, 'Z')
            app.filteredPower.Tag = 'filteredPower';
            app.filenameText = uilabel(app.GridLayout);
            app.filenameText.Tag = 'filename';
            app.filenameText.Text = 'No data loaded';
            app.filesizeText = uilabel(app.GridLayout);
            app.filesizeText.Tag = 'filesize';
            app.filesizeText.Text = 'No data loaded';
            app.LoadButton = uibutton(app.GridLayout, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Tag = 'load';
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Tag = 'save';
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.ChannelselectionPanel = uipanel(app.GridLayout);
            app.ChannelselectionPanel.Title = 'Channel selection';
            app.SelectchanneltypestofilterLabel = uilabel(app.ChannelselectionPanel);
            app.SelectchanneltypestofilterLabel.Text = 'Select channel types to filter';
            app.EEGCheckBox = uicheckbox(app.ChannelselectionPanel);
            app.EEGCheckBox.ValueChangedFcn = createCallbackFcn(app, @EEGCheckBoxValueChanged, true);
            app.EEGCheckBox.Tag = 'checkEEG';
            app.EEGCheckBox.Text = 'EEG';
            app.EEGCheckBox.Value = true;
            app.EOGCheckBox = uicheckbox(app.ChannelselectionPanel);
            app.EOGCheckBox.ValueChangedFcn = createCallbackFcn(app, @EOGCheckBoxValueChanged, true);
            app.EOGCheckBox.Tag = 'checkEOG';
            app.EOGCheckBox.Text = 'EOG';
            app.EOGCheckBox.Value = true;
            app.ECGCheckBox = uicheckbox(app.ChannelselectionPanel);
            app.ECGCheckBox.ValueChangedFcn = createCallbackFcn(app, @ECGCheckBoxValueChanged, true);
            app.ECGCheckBox.Tag = 'checkECG';
            app.ECGCheckBox.Text = 'ECG';
            app.ECGCheckBox.Value = true;
            app.EMGCheckBox = uicheckbox(app.ChannelselectionPanel);
            app.EMGCheckBox.ValueChangedFcn = createCallbackFcn(app, @EMGCheckBoxValueChanged, true);
            app.EMGCheckBox.Tag = 'checkEMG';
            app.EMGCheckBox.Text = 'EMG';
            app.EMGCheckBox.Value = true;
            app.GSRCheckBox = uicheckbox(app.ChannelselectionPanel);
            app.GSRCheckBox.ValueChangedFcn = createCallbackFcn(app, @GSRCheckBoxValueChanged, true);
            app.GSRCheckBox.Tag = 'checkGSR';
            app.GSRCheckBox.Text = 'GSR';
            app.GSRCheckBox.Value = true;
            app.FiltersettingsButtonGroup = uibuttongroup(app.GridLayout);
            app.FiltersettingsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FiltersettingsButtonGroupSelectionChanged, true);
            app.FiltersettingsButtonGroup.Title = 'Filter settings';
            app.FiltersettingsButtonGroup.Tag = 'filterSettings';
            app.BandfilterButton = uiradiobutton(app.FiltersettingsButtonGroup);
            app.BandfilterButton.Tag = 'bandFilter';
            app.BandfilterButton.Text = 'Band filter';
            app.BandfilterButton.Value = true;
            app.BandFilterOpts = uibuttongroup(app.FiltersettingsButtonGroup);
            app.BandFilterOpts.Tag = 'bandFilterPanel';
            app.BandstopButton = uiradiobutton(app.BandFilterOpts);
            app.BandstopButton.Tag = 'bandStop';
            app.BandstopButton.Text = 'Band stop';
            app.BandstopButton.Value = true;
            app.BandpassButton_2 = uiradiobutton(app.BandFilterOpts);
            app.BandpassButton_2.Tag = 'bandPass';
            app.BandpassButton_2.Text = 'Band pass';
            app.LowerlimitEditFieldLabel = uilabel(app.BandFilterOpts);
            app.LowerlimitEditFieldLabel.Text = 'Lower limit';
            app.UpperlimitEditFieldLabel = uilabel(app.BandFilterOpts);
            app.UpperlimitEditFieldLabel.Text = 'Upper limit';
            app.LowerlimitEditField = uieditfield(app.BandFilterOpts, 'numeric');
            app.LowerlimitEditField.Tag = 'lowerLimit';
            app.LowerlimitEditField.Value = 49;
            app.UpperlimitEditField = uieditfield(app.BandFilterOpts, 'numeric');
            app.UpperlimitEditField.Tag = 'upperLimit';
            app.UpperlimitEditField.Value = 51;
            app.CutofffilterButton = uiradiobutton(app.FiltersettingsButtonGroup);
            app.CutofffilterButton.Tag = 'cutoffFilter';
            app.CutofffilterButton.Text = 'Cut-off filter';
            app.CutoffFiltOpts = uibuttongroup(app.FiltersettingsButtonGroup);
            app.CutoffFiltOpts.Tag = 'cutoffFilterPanel';
            app.CutofffrequencyEditFieldLabel = uilabel(app.CutoffFiltOpts);
            app.CutofffrequencyEditFieldLabel.Text = 'Cut-off frequency';
            app.LowpassButton = uiradiobutton(app.CutoffFiltOpts);
            app.LowpassButton.Tag = 'lowPass';
            app.LowpassButton.Enable = 'off';
            app.LowpassButton.Text = 'Low pass';
            app.LowpassButton.Value = true;
            app.HighPassButton = uiradiobutton(app.CutoffFiltOpts);
            app.HighPassButton.Tag = 'highPass';
            app.HighPassButton.Enable = 'off';
            app.HighPassButton.Text = 'High Pass';
            app.CutofffrequencyEditField = uieditfield(app.CutoffFiltOpts, 'numeric');
            app.CutofffrequencyEditField.Tag = 'cutoffFreq';
            app.CutofffrequencyEditField.Enable = 'off';
            app.CutofffrequencyEditField.Value = 49;
            app.AttenuationdBfilterstrengthLabel = uilabel(app.FiltersettingsButtonGroup);
            app.AttenuationdBfilterstrengthLabel.Text = 'Attenuation (dB; filter strength)';
            app.AttenuationdBfilterstrengthEditField = uieditfield(app.FiltersettingsButtonGroup, 'numeric');
            app.AttenuationdBfilterstrengthEditField.Tag = 'attenuation';
            app.AttenuationdBfilterstrengthEditField.Value = 30;
            app.ApplyfilterButton = uibutton(app.FiltersettingsButtonGroup, 'push');
            app.ApplyfilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyfilterButtonPushed, true);
            app.ApplyfilterButton.Tag = 'applyFilter';
            app.ApplyfilterButton.Text = 'Apply filter';
            app.Panel = uipanel(app.GridLayout);
            app.ChannelIndex = uilabel(app.Panel);
            app.ChannelIndex.Text = 'Channel:  - / - ';
            app.previousButton = uibutton(app.Panel, 'push');
            app.previousButton.ButtonPushedFcn = createCallbackFcn(app, @previousButtonPushed, true);
            app.previousButton.Tag = 'previousButton';
            app.previousButton.Enable = 'off';
            app.previousButton.Text = '<';
            app.nextButton = uibutton(app.Panel, 'push');
            app.nextButton.ButtonPushedFcn = createCallbackFcn(app, @nextButtonPushed, true);
            app.nextButton.Tag = 'nextButton';
            app.nextButton.Enable = 'off';
            app.nextButton.Text = '>';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Filter_tool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end