classdef Import_tool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImporttoolUIFigure            matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        SubjectIDEditField            matlab.ui.control.EditField
        SubjectIDEditFieldLabel       matlab.ui.control.Label
        ImportButton                  matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        ThirddimDropDown              matlab.ui.control.DropDown
        ThirddimensionrepresentsDropDownLabel  matlab.ui.control.Label
        ColumsrepresentDropDownLabel  matlab.ui.control.Label
        RowsrepresentDropDownLabel    matlab.ui.control.Label
        SamplingrateEditFieldLabel    matlab.ui.control.Label
        ColumsDropDown                matlab.ui.control.DropDown
        RowsDropDown                  matlab.ui.control.DropDown
        SamplingrateEditField         matlab.ui.control.NumericEditField
        FileformatDropDown            matlab.ui.control.DropDown
        LoadButton                    matlab.ui.control.Button
        Panel                         matlab.ui.container.Panel
        filenameNew                   matlab.ui.control.Label
        filename                      matlab.ui.control.Label
        FileformatDropDownLabel       matlab.ui.control.Label
    end


    properties (Access = private)
        EEG     % The data in the new format
        data    % data % the data to be imported
    end

    methods (Access = private)

        function drawTable(app)
            % draw the table listing all the channels in the data
            % the user will then be able to manualy edit the channel labels
            % and types
            nrofchannels = getnrofchannels(app);
            app.EEG.channelnumbers = string(1:nrofchannels)';
            app.EEG.channelLabels= compose("Chan%02i",1:nrofchannels)';
            app.EEG.channelTypes = repelem("EEG",nrofchannels)';
            value = app.FileformatDropDown.Value;
            % identify marker channals
            if find(ismember(["EEG recorder (MobiLab)","PsyPhy"],value))
                for ichan = 1:nrofchannels
                    % detect marker channel as either 
                    % - consisting of 5V signals 
                    % - consisting of only integer values
                    if round(max(diff(app.data(:,ichan,1))),4)==5 || ~any(rem(app.data(:,ichan,1),1))
                        app.EEG.channelLabels(ichan) = "Marker";
                        app.EEG.channelTypes(ichan) = "Marker";
                    end
                end
            end
            app.UITable.Data = [app.EEG.channelnumbers, app.EEG.channelLabels, app.EEG.channelTypes];
        end

        function nrofchannels = getnrofchannels(app)
            % calulate the number of channels based on the data size and
            % data format.
            value = app.FileformatDropDown.Value;
            items = app.FileformatDropDown.Items;
            switch find(ismember(items,value))
                case 1
                    nrofchannels = size(app.data,2);
                case 2
                    nrofchannels = size(app.data,2);
                case 3
                    dims = string({app.RowsDropDown.Value, ...
                        app.ColumsDropDown.Value, ...
                        app.ThirddimDropDown.Value});
                    chanDim = find(ismember(dims,'channels'));
                    nrofchannels = size(app.data,chanDim);
            end
        end

        function dims = getDims(app)
            dims = [...
                string(app.RowsDropDown.Value), ...
                string(app.ColumsDropDown.Value), ...
                ];
            if size(app.data,3) > 1
                dims = [ dims app.ThirddimDropDown.Value];
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
                % choose file to load
            % this code does not work on some Macs
            %                [file, filepath]= uigetfile(genpath([cd filesep 'Data' filesep '*.mat']));
            % simpler version of the code is more stable
            [file, filepath]= uigetfile('Data');
            [~, ~, ext] = fileparts(file);
            if ~file
                app.EEG = [];
                disp('No file selected')
                return
            elseif ~strcmp(ext, '.mat')
                opts.WindowStyle = 'modal';
                opts.Interpreter = 'tex';
                msg = sprintf("The selected file dis not a .mat file.\nPlease select a .mat file to import.");
                errordlg(['\fontsize{20}' msg],'Wrong file selected',opts)
                uiwait
                figure(app.ImporttoolUIFigure)
                return                
            end
            % intiate EEG struct
            app.EEG = struct( ...
                'filename',[], ...
                'fsample',[], ...
                'dims',[], ...
                'domain',[], ...
                'subjectID',[], ...
                'data',[], ...
                'channelLabels',[], ...
                'channelTypes',[], ...
                'sampleinfo',[], ...
                'time',[], ...
                'history',[] ...
                );


            % load selected file
            load([filepath file]);
            if exist('EEG','var')
                opts.WindowStyle = 'modal';
                opts.Interpreter = 'tex';
                msg = sprintf("The selected file is already in the correct format and does not need to be imported.");
                errordlg(['\fontsize{20}' msg],'Wrong file selected',opts)
                uiwait
            elseif ~exist('data','var')
                opts.WindowStyle = 'modal';
                opts.Interpreter = 'tex';
                msg = sprintf("The selected file does not contain the rigth type of data.\nPlease choose another file.");
                errordlg(['\fontsize{20}' msg],'Wrong file selected',opts)
                uiwait
            else
                
                app.data = data;
                % app.EEG.data = data;
                % clear data

                app.filename.Text = ['Selected file: ' file];
                app.filenameNew.Text = "";
                app.EEG.filename = file(1:end-4);
                if size(app.data,3)>1
                    app.ThirddimDropDown.Visible = 'on';
                end


                app.EEG.fsample = app.SamplingrateEditField.Value;

                %% hard code some properties for now, maybe editable later on
                app.EEG.domain = 'time';
                %%


                drawTable(app)
            end
            figure(app.ImporttoolUIFigure)
        end

        % Value changed function: FileformatDropDown
        function FileformatDropDownValueChanged(app, event)
            value = app.FileformatDropDown.Value;
            items = app.FileformatDropDown.Items;
            switch find(ismember(items,value))
                case 1
                    app.SamplingrateEditField.Value = 256;
                    app.SamplingrateEditField.Enable = 0;
                    app.RowsDropDown.Value = 'samples';
                    app.RowsDropDown.Enable = 0;
                    app.ColumsDropDown.Value = 'channels';
                    app.ColumsDropDown.Enable = 0;
                case 2
                    app.SamplingrateEditField.Value = 500;
                    app.SamplingrateEditField.Enable = 1;
                    app.RowsDropDown.Value = 'samples';
                    app.RowsDropDown.Enable = 0;
                    app.ColumsDropDown.Value = 'channels';
                    app.ColumsDropDown.Enable = 0;
                case 3
                    app.SamplingrateEditField.Value = 500;
                    app.SamplingrateEditField.Enable = 1;
                    app.RowsDropDown.Enable = 1;
                    app.ColumsDropDown.Enable = 1;
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            switch indices(2)
                case 2 % changed Channel Label
                    app.EEG.channelLabels(indices(1)) = newData;
                case 3 % changed Channel Type
                    %% TO DO: add input check to allow only certain types
                    app.EEG.channelTypes(indices(1)) = newData;
            end
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            %             app.EEG = struct( ...
            %                 'filename',[], ...
            %                 'fsample',[], ...
            %                 'dims',[], ...
            %                 'domain',[], ...
            %                 'data',[], ...
            %                 'channelLabels',[], ...
            %                 'channelTypes',[], ...
            %                 'sampleinfo',[], ...
            %                 'time',[], ...
            %                 'history',[] ...
            %                 );

            %% Update all fields before saving
            % overwrite EEG.data from selected file
            app.EEG.data = app.data;
            app.EEG.dims = getDims(app);

            nrofsamples = size(app.EEG.data, find(strcmp(app.EEG.dims,"samples")));
            app.EEG.time = (0:nrofsamples-1) / app.EEG.fsample;
            app.EEG.fsample = app.SamplingrateEditField.Value;

            app.EEG.subjectID = string(app.SubjectIDEditField.Value);

            app.EEG.channelLabels = app.UITable.Data(:,2)';
            app.EEG.channelTypes = app.UITable.Data(:,3)';

            if strcmp(app.FileformatDropDown.Value, 'PsyPhy')
                nonMarkerChans = ~ismember(app.EEG.channelTypes, "Marker");
                % check the interquartile range of the signal to see
                % whether it is in the correct range (below 1e6). If not
                % scale it down to microvolts
                %
                % TO-DO: double check whether this should be applied to all
                % physiological channel or only some.

                % by default do not scale the data
                scaled = 0;
                if iqr( app.EEG.data(:,1) ) > 1e6
                    app.EEG.data(:,nonMarkerChans) = app.EEG.data(:,nonMarkerChans) / 1e6;
                    scaled = 1;
                end
                % invert signal (because psyphy amp records inverted from
                % what is common.
                app.EEG.data(:,nonMarkerChans) = -app.EEG.data(:,nonMarkerChans);
            end

            app.EEG.history = sprintf("Data imported at %s", char(datetime("now")));

            if scaled
                app.EEG.history = app.EEG.history + sprintf("\nData is scaled at import to microVolts");
            end

            [~, newfilename ] = EEGSaveData(app.EEG, 'import');
            app.filenameNew.Text = "Saved as: " + newfilename + ".mat";
            app.filenameNew.FontColor = [0 1 .2];
            figure(app.ImporttoolUIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImporttoolUIFigure = uifigure('Visible', 'off');
            app.ImporttoolUIFigure.Name = 'Import tool';
            app.GridLayout = uigridlayout(app.ImporttoolUIFigure);
            app.FileformatDropDownLabel = uilabel(app.GridLayout);
            app.FileformatDropDownLabel.Text = 'File format';
            app.Panel = uipanel(app.GridLayout);
            app.filename = uilabel(app.Panel);
            app.filename.Text = 'No data loaded';
            app.filenameNew = uilabel(app.Panel);
            app.filenameNew.Text = '';
            app.LoadButton = uibutton(app.GridLayout, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.FileformatDropDown = uidropdown(app.GridLayout);
            app.FileformatDropDown.Items = {'EEG recorder (MobiLab)', 'PsyPhy', 'Custom'};
            app.FileformatDropDown.ValueChangedFcn = createCallbackFcn(app, @FileformatDropDownValueChanged, true);
            app.FileformatDropDown.Value = 'PsyPhy';
            app.SamplingrateEditField = uieditfield(app.GridLayout, 'numeric');
            app.SamplingrateEditField.Value = 500;
            app.RowsDropDown = uidropdown(app.GridLayout);
            app.RowsDropDown.Items = {'samples', 'channels', 'trials', 'subjects', 'conditions'};
            app.RowsDropDown.Enable = 'off';
            app.RowsDropDown.Value = 'samples';
            app.ColumsDropDown = uidropdown(app.GridLayout);
            app.ColumsDropDown.Items = {'samples', 'channels', 'trials', 'subjects', 'conditions'};
            app.ColumsDropDown.Enable = 'off';
            app.ColumsDropDown.Value = 'channels';
            app.SamplingrateEditFieldLabel = uilabel(app.GridLayout);
            app.SamplingrateEditFieldLabel.Text = 'Sampling rate';
            app.RowsrepresentDropDownLabel = uilabel(app.GridLayout);
            app.RowsrepresentDropDownLabel.Text = 'Rows represent';
            app.ColumsrepresentDropDownLabel = uilabel(app.GridLayout);
            app.ColumsrepresentDropDownLabel.Text = 'Colums represent';
            app.ThirddimensionrepresentsDropDownLabel = uilabel(app.GridLayout);
            app.ThirddimensionrepresentsDropDownLabel.Visible = 'off';
            app.ThirddimensionrepresentsDropDownLabel.Text = 'Third dimension represents';
            app.ThirddimDropDown = uidropdown(app.GridLayout);
            app.ThirddimDropDown.Items = {'samples', 'channels', 'trials', 'subjects', 'conditions'};
            app.ThirddimDropDown.Visible = 'off';
            app.ThirddimDropDown.Value = 'trials';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Channel Nr'; 'Channel Label'; 'Channel Type'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.ImportButton = uibutton(app.GridLayout, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.SubjectIDEditFieldLabel = uilabel(app.GridLayout);
            app.SubjectIDEditFieldLabel.Text = 'Subject ID';
            app.SubjectIDEditField = uieditfield(app.GridLayout, 'text');
            % Show the figure after all components are created
            app.ImporttoolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Import_tool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImporttoolUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImporttoolUIFigure)
        end
    end
end